classdef detect_shape_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        LoadImageButton         matlab.ui.control.Button
        NumberOfCirclesLabel    matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        EditField               matlab.ui.control.NumericEditField
        NumberOfSquaresLabel    matlab.ui.control.Label
        EditField_2Label        matlab.ui.control.Label
        EditField_2             matlab.ui.control.NumericEditField
        NumberOfTrianglesLabel  matlab.ui.control.Label
        EditField_3Label        matlab.ui.control.Label
        EditField_3             matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
            fullname = [filepath, filename];
            ImageFile = imread(fullname);
            imagesc(app.UIAxes, ImageFile);
            
            binaryImage = binarize(ImageFile);
            
            [circles, squares, triangles] = detect_shape(binaryImage);
            
            app.EditField.Value = circles;
            app.EditField_2.Value = squares;
            app.EditField_3.Value = triangles;
        end

        % Callback function
        function FindRectangleButtonPushed(app, event)
            
        end

        % Callback function
        function FindCircleButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.NumberOfCirclesLabel = uilabel(app.UIFigure);
            app.NumberOfCirclesLabel.Text = 'Number Of Circles';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberOfSquaresLabel = uilabel(app.UIFigure);
            app.NumberOfSquaresLabel.Text = 'Number Of Squares';
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NumberOfTrianglesLabel = uilabel(app.UIFigure);
            app.NumberOfTrianglesLabel.Text = 'Number Of Triangles';
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detect_shape_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end